classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        DButton_3  matlab.ui.control.Button
        CButton_3  matlab.ui.control.Button
        AButton_2  matlab.ui.control.Button
        GButton_2  matlab.ui.control.Button
        FButton_2  matlab.ui.control.Button
        DButton_2  matlab.ui.control.Button
        CButton_2  matlab.ui.control.Button
        AButton    matlab.ui.control.Button
        CButton    matlab.ui.control.Button
        GButton    matlab.ui.control.Button
        FButton    matlab.ui.control.Button
        DButton    matlab.ui.control.Button
        E5Button   matlab.ui.control.Button
        D5Button   matlab.ui.control.Button
        C3Button   matlab.ui.control.Button
        C5Button   matlab.ui.control.Button
        B3Button   matlab.ui.control.Button
        A3Button   matlab.ui.control.Button
        G3Button   matlab.ui.control.Button
        F3Button   matlab.ui.control.Button
        E3Button   matlab.ui.control.Button
        D3Button   matlab.ui.control.Button
        B4Button   matlab.ui.control.Button
        A4Button   matlab.ui.control.Button
        G4Button   matlab.ui.control.Button
        F4Button   matlab.ui.control.Button
        E4Button   matlab.ui.control.Button
        D4Button   matlab.ui.control.Button
        C4Button   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UIFigureCloseRequest(app, event)
            delete(app)

        end

        % Button pushed function: C4Button
        function C4ButtonPushed(app, event)


            % Ange frekvensen för C-tonen (261.63 Hz för C4)
            frekvens = 261.63;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;
            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för C-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: D4Button
        function D4ButtonPushed(app, event)
            % Ange frekvensen för D-tonen (293.66 Hz för D4)
            frekvens = 293.66;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;

            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för D-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Callback function
        function UIFigureKeyRelease(app, event)

        end

        % Callback function
        function UIFigureKeyRelease2(app, event)

        end

        % Button pushed function: E4Button
        function E4ButtonPushed(app, event)
            % Ange frekvensen för E-tonen (329.63 Hz för E4)
            frekvens = 329.63;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;
            
            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för E-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: F4Button
        function F4ButtonPushed(app, event)
            % Ange frekvensen för F-tonen (349.23 Hz för F4)
            frekvens = 349.23;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35; 

            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för F-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: G4Button
        function G4ButtonPushed(app, event)
            % Ange frekvensen för G-tonen (392.00 Hz för G4)
            frekvens = 392.00;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;

            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för G-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: A4Button
        function A4ButtonPushed(app, event)
            % Ange frekvensen för A-tonen (440.00 Hz för A4)
            frekvens = 440.00;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;

            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för A-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: B4Button
        function B4ButtonPushed(app, event)
            % Ange frekvensen för B-tonen (493.88 Hz för B4)
            frekvens = 493.88;

            % Ange längden på ljudet (i sekunder)
            tid = 0.35;

            % Skapa en tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen (typisk för ljud)

            % Skapa ljudsignalen för B-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: C3Button
        function C3ButtonPushed(app, event)
            % Frekvensen för C3-tonen
            frekvens = 130.81;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);

        end

        % Button pushed function: D3Button
        function D3ButtonPushed(app, event)
            % Frekvensen för D3-tonen
            frekvens = 146.83;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);  
        end

        % Button pushed function: E3Button
        function E3ButtonPushed(app, event)
            % Frekvensen för E3-tonen
            frekvens = 164.81;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: F3Button
        function F3ButtonPushed(app, event)
            % Frekvensen för F3-toneN
            frekvens = 174.61;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: G3Button
        function G3ButtonPushed(app, event)
            % Frekvensen för G3-tonen
            frekvens = 196.00;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: A3Button
        function A3ButtonPushed(app, event)
            % Frekvensen för A3-tonen
            frekvens = 220.00;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100); 
        end

        % Button pushed function: B3Button
        function B3ButtonPushed(app, event)
            % Frekvensen för B3-tonen
            frekvens = 246.94;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: C5Button
        function C5ButtonPushed(app, event)
            % Frekvensen för C5-tonen
            frekvens = 523.25;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: D5Button
        function D5ButtonPushed(app, event)
            % Frekvensen för D5-tonen
            frekvens = 587.33;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: E5Button
        function E5ButtonPushed(app, event)
            % Frekvensen för E5-tonen
            frekvens = 659.25;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            % Frekvensen för C#3-tonen
            frekvens = 138.591;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            % Frekvensen för D#3-tonen
            frekvens = 155.564;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
            % Frekvensen för F#3-tonen
            frekvens = 184.997;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            % Frekvensen för G#3-tonen
            frekvens = 207.652;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            % Frekvensen för A#3-tonen
            frekvens = 233.082;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: CButton_2
        function CButton_2Pushed(app, event)
            % Frekvensen för C#4-tonen
            frekvens = 277.183;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: DButton_2
        function DButton_2Pushed(app, event)
            % Frekvensen för D#4-tonen
            frekvens = 311.127;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: FButton_2
        function FButton_2Pushed(app, event)
            % Frekvensen för F#4-tonen
            frekvens = 369.994;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: GButton_2
        function GButton_2Pushed(app, event)
            % Frekvensen för G#4-tonen
            frekvens = 415.305;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: AButton_2
        function AButton_2Pushed(app, event)
            % Frekvensen för A#4-tonen
            frekvens = 466.164;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: CButton_3
        function CButton_3Pushed(app, event)
            % Frekvensen för C#5-tonen
            frekvens = 554.365;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end

        % Button pushed function: DButton_3
        function DButton_3Pushed(app, event)
            % Frekvensen för D#5-tonen
            frekvens = 622.254;

            % Längden på ljudet (i sekunder)
            tid = 0.35;

            % Tidsvektor för ljudet
            t = linspace(0, tid, tid*44100); % 44100 är samplingsfrekvensen

            % Ljudsignalen för C3-tonen
            ton = sin(2*pi*frekvens*t);

            % Spela upp ljudet
            sound(ton, 44100);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.C4Button = uibutton(app.UIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4Button.Text = 'C4';
            app.D4Button = uibutton(app.UIFigure, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4Button.Text = 'D4';
            app.E4Button = uibutton(app.UIFigure, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4ButtonPushed, true);
            app.E4Button.Text = 'E4';
            app.F4Button = uibutton(app.UIFigure, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4Button.Text = 'F4';
            app.G4Button = uibutton(app.UIFigure, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4ButtonPushed, true);
            app.G4Button.Text = 'G4';
            app.A4Button = uibutton(app.UIFigure, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4Button.Text = 'A4';
            app.B4Button = uibutton(app.UIFigure, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4Button.Text = 'B4';
            app.D3Button = uibutton(app.UIFigure, 'push');
            app.D3Button.ButtonPushedFcn = createCallbackFcn(app, @D3ButtonPushed, true);
            app.D3Button.Text = 'D3';
            app.E3Button = uibutton(app.UIFigure, 'push');
            app.E3Button.ButtonPushedFcn = createCallbackFcn(app, @E3ButtonPushed, true);
            app.E3Button.Text = 'E3';
            app.F3Button = uibutton(app.UIFigure, 'push');
            app.F3Button.ButtonPushedFcn = createCallbackFcn(app, @F3ButtonPushed, true);
            app.F3Button.Text = 'F3';
            app.G3Button = uibutton(app.UIFigure, 'push');
            app.G3Button.ButtonPushedFcn = createCallbackFcn(app, @G3ButtonPushed, true);
            app.G3Button.Text = 'G3';
            app.A3Button = uibutton(app.UIFigure, 'push');
            app.A3Button.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.A3Button.Text = 'A3';
            app.B3Button = uibutton(app.UIFigure, 'push');
            app.B3Button.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3Button.Text = 'B3';
            app.C5Button = uibutton(app.UIFigure, 'push');
            app.C5Button.ButtonPushedFcn = createCallbackFcn(app, @C5ButtonPushed, true);
            app.C5Button.Text = 'C5';
            app.C3Button = uibutton(app.UIFigure, 'push');
            app.C3Button.ButtonPushedFcn = createCallbackFcn(app, @C3ButtonPushed, true);
            app.C3Button.Text = 'C3';
            app.D5Button = uibutton(app.UIFigure, 'push');
            app.D5Button.ButtonPushedFcn = createCallbackFcn(app, @D5ButtonPushed, true);
            app.D5Button.Text = 'D5';
            app.E5Button = uibutton(app.UIFigure, 'push');
            app.E5Button.ButtonPushedFcn = createCallbackFcn(app, @E5ButtonPushed, true);
            app.E5Button.Text = 'E5';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D#';
            app.FButton = uibutton(app.UIFigure, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F#';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G#';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C#';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A#';
            app.CButton_2 = uibutton(app.UIFigure, 'push');
            app.CButton_2.ButtonPushedFcn = createCallbackFcn(app, @CButton_2Pushed, true);
            app.CButton_2.Text = 'C#';
            app.DButton_2 = uibutton(app.UIFigure, 'push');
            app.DButton_2.ButtonPushedFcn = createCallbackFcn(app, @DButton_2Pushed, true);
            app.DButton_2.Text = 'D#';
            app.FButton_2 = uibutton(app.UIFigure, 'push');
            app.FButton_2.ButtonPushedFcn = createCallbackFcn(app, @FButton_2Pushed, true);
            app.FButton_2.Text = 'F#';
            app.GButton_2 = uibutton(app.UIFigure, 'push');
            app.GButton_2.ButtonPushedFcn = createCallbackFcn(app, @GButton_2Pushed, true);
            app.GButton_2.Text = 'G#';
            app.AButton_2 = uibutton(app.UIFigure, 'push');
            app.AButton_2.ButtonPushedFcn = createCallbackFcn(app, @AButton_2Pushed, true);
            app.AButton_2.Text = 'A#';
            app.CButton_3 = uibutton(app.UIFigure, 'push');
            app.CButton_3.ButtonPushedFcn = createCallbackFcn(app, @CButton_3Pushed, true);
            app.CButton_3.Text = 'C#';
            app.DButton_3 = uibutton(app.UIFigure, 'push');
            app.DButton_3.ButtonPushedFcn = createCallbackFcn(app, @DButton_3Pushed, true);
            app.DButton_3.Text = 'D#';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end